classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        theta1SliderLabel       matlab.ui.control.Label
        theta1Slider            matlab.ui.control.Slider
        theta2SliderLabel       matlab.ui.control.Label
        theta2Slider            matlab.ui.control.Slider
        ForwardKinematicsLabel  matlab.ui.control.Label
        UpLabel                 matlab.ui.control.Label
        upModeLabel             matlab.ui.control.Label
        DownLabel               matlab.ui.control.Label
        downModeLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        r % [r1;r2;r3]
        theta %[theta1;theta2]
        A1 
        A2
        b1
        b2
        h_a1b1; %matlab plot handle of active link A1B1
        h_a2b2; %matlab plot handle of active link A2B2
        h_b1c1b2; %matlab plot handle of up-configuration
        h_b1c2b2;
        fk_nSol;%forward kinematics solution number;
        fk_up;
        fk_down;
    end
    
    methods (Access = private)
        function results = handleThetaChanged(app)
            assignin('base','theta',app.theta); %export to matlab 'base' workspace
            app.b1 = [app.r(1) * cosd(app.theta(1)) - app.r(3); app.r(1) * sind(app.theta(1))];
            app.b2 = [app.r(1) * cosd(app.theta(2)) + app.r(3); app.r(1) * sind(app.theta(2))];
            drawActiveLink(app);
            
            %do forward kinematics
            [app.fk_nSol, app.fk_up, app.fk_down] = forwardKinematics(app.r, app.theta);
            
            assignin('base', 'up', app.fk_up);
            assignin('base', 'down', app.fk_down);
            drawPassiveLink(app);
            
            updateFkModeLabel(app);
            results = 0;
        end
        
        function crossChar = cross2char(~, crossValue)
             if (crossValue < 1E-6)
                crossChar = '-';
             elseif (crossValue > 1E-6)
                crossChar = '+';
            else
                crossChar = 'c';
            end
        end
    end
    
    methods (Access = public)
        
        function results = drawActiveLink(app)
            %app.h_a1b1.Visible = 'off'; %hide old plot 
            %app.h_a2b2.Visible = 'off';
            delete(app.h_a1b1);
            delete(app.h_a2b2);
            app.h_a1b1 = plot(app.UIAxes, [app.A1(1) app.b1(1)] , [app.A1(2) app.b1(2)], '-go');
            app.h_a2b2 = plot(app.UIAxes, [app.A2(1) app.b2(1)] , [app.A2(2) app.b2(2)], '-go');
            results = 0;
        end
        
        function results = drawPassiveLink(app)
%             app.h_b1c1b2.Visible = 'off'; %hide old plot 
%             app.h_b1c2b2.Visible = 'off';
            delete(app.h_b1c1b2);
            delete(app.h_b1c2b2);
            if (app.fk_nSol < 1)
                return;
            end

            app.h_b1c1b2 = plot(app.UIAxes, [app.b1(1) app.fk_up(1) app.b2(1)] , [app.b1(2) app.fk_up(2) app.b2(2)], '-mo');
            
            if (app.fk_nSol == 2)
                app.h_b1c2b2 = plot(app.UIAxes, [app.b1(1) app.fk_down(1) app.b2(1)] , [app.b1(2) app.fk_down(2) app.b2(2)], ':go');
            end
            
            results = 0;                    
        end
    
        function results = updateFkModeLabel(app)
            if (app.fk_nSol < 1)
                app.upModeLabel.Text = 'xx';
                app.downModeLabel.Text = 'xx';
                return;
            end

            A1C1 = app.fk_up - app.A1; %vector A1P
            A1B1 = app.b1 - app.A1;    %vector A1B1
            leftCross = A1C1(1) * A1B1(2) - A1B1(1) * A1C1(2);
            
            A2C1 = app.fk_up - app.A2;
            A2B2 = app.b2 - app.A2;
            rightCross = A2C1(1) * A2B2(2) - A2B2(1) * A2C1(2);
            
            app.upModeLabel.Text = [cross2char(app, leftCross) , cross2char(app, rightCross)];
            
            A1C2 = app.fk_down - app.A1; %vector A1P
            A1B1 = app.b1 - app.A1;    %vector A1B1
            leftCross = A1C2(1) * A1B1(2) - A1B1(1) * A1C2(2);
            
            A2C2 = app.fk_down - app.A2;
            A2B2 = app.b2 - app.A2;
            rightCross = A2C2(1) * A2B2(2) - A2B2(1) * A2C2(1);
            
            app.downModeLabel.Text = [cross2char(app, leftCross) , cross2char(app, rightCross)];
            results = 0;                    
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.r = [1.2; 1.0; 0.8];
            app.theta = [0; 0];
            app.A1 = [-app.r(3); 0];
            app.A2 = [app.r(3); 0];
            app.b1 = [app.r(1) * cosd(app.theta(1)) - app.r(3); app.r(1) * sind(app.theta(1))];
            app.b2 = [app.r(1) * cosd(app.theta(2)) + app.r(3); app.r(1) * sind(app.theta(2))];
            loci(app.UIAxes, app.r); %draw singularity curve
            handleThetaChanged(app);
%             app.h_a1b1 = plot(app.UIAxes, [app.A1(1) app.b1(1)] , [app.A1(2) app.b1(2)], '-go');
%             app.h_a2b2 = plot(app.UIAxes, [app.A2(1) app.b2(1)] , [app.A2(2) app.b2(2)], '-go');
        end

        % Value changed function: theta1Slider
        function theta1SliderValueChanged(app, event)
            app.theta = [app.theta1Slider.Value; app.theta2Slider.Value;];
            handleThetaChanged(app);
        end

        % Value changed function: theta2Slider
        function theta2SliderValueChanged(app, event)
            app.theta = [app.theta1Slider.Value; app.theta2Slider.Value;];
            handleThetaChanged(app);
        end

        % Value changing function: theta1Slider
        function theta1SliderValueChanging(app, event)
            app.theta = [event.Value; app.theta2Slider.Value;];
            handleThetaChanged(app);
        end

        % Value changing function: theta2Slider
        function theta2SliderValueChanging(app, event)
            app.theta = [app.theta1Slider.Value; event.Value;];
            handleThetaChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.theta1SliderLabel = uilabel(app.UIFigure);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.UIFigure);
            app.theta1Slider.Limits = [-360 360];
            app.theta1Slider.ValueChangedFcn = createCallbackFcn(app, @theta1SliderValueChanged, true);
            app.theta1Slider.ValueChangingFcn = createCallbackFcn(app, @theta1SliderValueChanging, true);
            app.theta2SliderLabel = uilabel(app.UIFigure);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.UIFigure);
            app.theta2Slider.Limits = [-360 360];
            app.theta2Slider.ValueChangedFcn = createCallbackFcn(app, @theta2SliderValueChanged, true);
            app.theta2Slider.ValueChangingFcn = createCallbackFcn(app, @theta2SliderValueChanging, true);
            app.ForwardKinematicsLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics:';
            app.UpLabel = uilabel(app.UIFigure);
            app.UpLabel.Text = 'Up:';
            app.upModeLabel = uilabel(app.UIFigure);
            app.upModeLabel.Text = 'upMode';
            app.DownLabel = uilabel(app.UIFigure);
            app.DownLabel.Text = 'Down:';
            app.downModeLabel = uilabel(app.UIFigure);
            app.downModeLabel.Text = 'downMode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end